classdef packFile7z < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        v100UIFigure    matlab.ui.Figure
        Panel7z         matlab.ui.container.Panel
        LabelZTZ        matlab.ui.control.Label
        LabelZTM        matlab.ui.control.Label
        ImageSet        matlab.ui.control.Image
        PanelSetRun     matlab.ui.container.Panel
        EditFieldFM     matlab.ui.control.EditField
        Label_8         matlab.ui.control.Label
        CheckBoxYC      matlab.ui.control.CheckBox
        DropDownYSJB    matlab.ui.control.DropDown
        Label_7         matlab.ui.control.Label
        DropDownXC      matlab.ui.control.DropDown
        Label_6         matlab.ui.control.Label
        DropDownGS      matlab.ui.control.DropDown
        Label_5         matlab.ui.control.Label
        SpinnerCJ       matlab.ui.control.Spinner
        Label_4         matlab.ui.control.Label
        ImageZX         matlab.ui.control.Image
        ImageLJ         matlab.ui.control.Image
        EditFieldPWlen  matlab.ui.control.NumericEditField
        Label_2         matlab.ui.control.Label
        EditFieldZF     matlab.ui.control.EditField
        CheckBoxZF      matlab.ui.control.CheckBox
        CheckBoxDX      matlab.ui.control.CheckBox
        CheckBoxXX      matlab.ui.control.CheckBox
        CheckBoxSZ      matlab.ui.control.CheckBox
        UITable         matlab.ui.control.Table
        EditField7zexe  matlab.ui.control.EditField
        Image           matlab.ui.control.Image
    end


    properties (Access = private)
        exe7z = fullfile(pwd,'7-Zip/7z.exe');  % 7z.exe文件所在路径
        mpath  = '';                           % 所选择的主文件夹路径
        mfinal = '';                           % 所选择的主文件夹路径最后一个文件夹的名称
        cpath = '';                            % 所设定层级的文件夹路径
        cfile = '';                            % 所设定层级的文件夹对应压缩文件名称
        mpwstr = '';                           % 每个压缩文件所对应的密码
        ttype  = '-t7z';                       % 7z的文件类型
        fext   = '.7z';                        % 待输出文件的压缩个数
        hename  = '-mhe=on';                   % 压缩时启用文件名加密
        cplevel = '9';                         % 压缩等级
        cpunum  = '4';                         % CPU线程数
        setflag = false;                       % 是否选择了主文件夹路径
    end

    methods (Access = private)

        function trans2PathLevel(app,pstr,level)
            pstr  = genpath(pstr);
            paths = strsplit(pstr,pathsep);      % 分割字符串得到各个路径
            paths(cellfun(@isempty,paths)) = [];

            plen        = length(paths);
            pathcell    = cell(plen,3);
            pathParts   = strsplit(paths{1}, filesep); % 分割路径
            clevel      = numel(pathParts);            % 当前路径的层级
            app.fext    = app.DropDownGS.Value;
            pathcell{1,1} = 0;
            pathcell{1,2} = paths{1};
            pathcell{1,3} = [pathParts{end},app.fext];
            app.mfinal    = pathParts{end};

            % 遍历每个路径，确定其层级并存入相应的字段
            for k = 2:numel(paths)
                pathParts = strsplit(paths{k}, filesep); % 分割路径
                tlevel    = numel(pathParts);            % 当前路径的层级
                switch(tlevel)
                    case clevel
                        pathcell{k,1} = 0;
                        pathcell{k,2} = paths{k};
                        pathcell{k,3} = [pathParts{end},app.fext];
                    case clevel+1
                        pathcell{k,1} = 1;
                        pathcell{k,2} = paths{k};
                        pathcell{k,3} = [pathParts{end},app.fext];
                    case clevel+2
                        pathcell{k,1} = 2;
                        pathcell{k,2} = paths{k};
                        pathcell{k,3} = [pathParts{end},app.fext];
                    case clevel+3
                        pathcell{k,1} = 3;
                        pathcell{k,2} = paths{k};
                        pathcell{k,3} = [pathParts{end},app.fext];
                    otherwise
                        continue;
                end
            end
            pathcell(cellfun(@isempty,pathcell(:,1)),:) = [];
            [~,idx] = sort([pathcell{:,1}]);
            pathcell = pathcell(idx,:);
            if level > 3
                level = 3;
            end
            while(true)
                loc = [pathcell{:,1}]==level;
                if any(loc)
                    app.cpath = pathcell(loc,2);
                    app.cfile = pathcell(loc,3);
                    break;
                end
                level = level-1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.v100UIFigure,'center');
            app.EditField7zexe.Value = fullfile(pwd,'7-Zip/7z.exe');
            app.LabelZTZ.Text = '欢迎使用【文件批量加密压缩】，程序已就绪！';
        end

        % Value changed function: CheckBoxZF
        function CheckBoxZFValueChanged(app, event)
            val = app.CheckBoxZF.Value;
            switch(val)
                case 0
                    app.EditFieldZF.Enable = "off";
                case 1
                    app.EditFieldZF.Enable = "on";
            end
        end

        % Image clicked function: ImageSet
        function ImageSetClicked(app, event)

            [file,path] = uigetfile( ...
                {'*.exe','7z exe (*.exe)';
                '*.*',  'All Files (*.*)'}, ...
                '请选择7z.exe文件的路径');
            if isequal(file,0)
                app.exe7z = '';
                app.EditField7zexe.Value = '';
                app.setflag = false;
                app.LabelZTZ.Text = '您已取消选择7z.exe路径!';
            else
                fname = fullfile(path,file);
                [~,tname,ext] = fileparts(fname);
                if strcmp(tname,'7z') && strcmp(ext,'.exe')
                    app.exe7z = fname;
                    app.EditField7zexe.Value = fname;
                    app.LabelZTZ.Text = '7z.exe文件路径设置完毕！';
                else
                    app.exe7z = '';
                    app.EditField7zexe.Value = '';
                    app.setflag = false;
                    app.LabelZTZ.Text = '必须选择7z.exe文件!';
                end
            end
        end

        % Image clicked function: ImageLJ
        function ImageLJClicked(app, event)

            selpath = uigetdir(pwd);
            if ~isequal(selpath,0)
                app.mpath = selpath;
                level     = app.SpinnerCJ.Value;
                try
                    trans2PathLevel(app,selpath,level);
                catch
                    app.setflag = false;
                    app.LabelZTZ.Text = '所选择的路径为非法路径，请重新选择后再试!';
                    return;
                end
                if ~isempty(app.cpath) && ~isempty(app.cfile)
                    len    = numel(app.cpath);
                    tcapth = app.cpath;               % 带处理路径
                    tcfile = app.cfile;
                    selpth = true(len,1);             % 是否选中
                    pwstr  = cell(len,1);             % 密码
                    pwlen  = app.EditFieldPWlen.Value;
                    szval  = double(app.CheckBoxSZ.Value);
                    xxval  = double(app.CheckBoxXX.Value);
                    dxval  = double(app.CheckBoxDX.Value);
                    zfval  = double(app.CheckBoxZF.Value);
                    zfstr  = app.EditFieldZF.Value;
                    if any([szval,xxval,dxval,zfval])
                        for k = 1:len
                            pwstr{k,1} = generatePasswordMex(pwlen, szval, xxval, dxval, zfval, 0, 0, zfstr);
                        end
                        cmst = zeros(len,1);
                        ttab = table(tcapth,selpth,pwstr,tcfile,cmst);
                        app.UITable.Data = ttab;
                        app.setflag      = true;
                        app.LabelZTZ.Text = '路径设置完成！';
                    else
                        app.mpwstr = '';
                        app.setflag = false;
                        app.LabelZTZ.Text = '请至少选择一种字符类型!';
                        return;
                    end
                else
                    app.setflag = false;
                    app.LabelZTZ.Text = '路径设置失败，请重新选择后再试!';
                    return;
                end
            else
                app.mpath = '';
                app.cpath = '';
            end

        end

        % Image clicked function: ImageZX
        function ImageZXClicked(app, event)
            if ~isempty(app.exe7z)
                if app.setflag
                    ttab   = app.UITable.Data;
                    selpth = ttab.selpth;
                    tcpath = ttab.tcapth(selpth);
                    pwstr  = ttab.pwstr(selpth);
                    tcfile = ttab.tcfile(selpth);
                    cmst   = ttab.cmst(selpth);
                    
                    len    = length(tcpath);
                    nsel   = true(len,1);
                    yspth  = cell(len,1);

                    yspath = fullfile(app.mpath,'压缩后');
                    if ~exist(yspath,'dir')
                        mkdir(yspath);
                    end

                    if ~isempty(app.EditFieldFM.Value)
                        fmstr = [app.EditFieldFM.Value, '_'];
                    else
                        fmstr = '';
                    end

                    for k = 1:len
                        app.LabelZTZ.Text = ['正在处理第 ',num2str(k),' 个文件...'];
                        pause(0.001);
                        outzip     = [yspath, filesep, fmstr, tcfile{k}];
                        yspth{k,1} = outzip;
                        app.cpunum = app.DropDownXC.Value;
                        if strcmp(app.cpunum,'none')
                            cpnum = '-mmt';
                        else
                            cpnum = ['-mmt=',app.cpunum];
                        end
                        cplvel = ['-mx=',app.DropDownYSJB.Value];
                        hestr  = app.hename;
                        cmdstr   = [app.exe7z,' a ',app.ttype, ' ',hestr,' ',cplvel,' ', cpnum,' -p"', pwstr{k},'" ',outzip, ' ',tcpath{k}];
                        % 使用system函数执行命令
                        exitCode = system(cmdstr);
                        if exitCode ~= 0
                            nsel(k) = false;
                        end
                    end

                    tcfile = tcfile(nsel);
                    pwstr  = pwstr(nsel);
                    yspth  = yspth(nsel);
                    cmst(selpth) = nsel;

                    ttab = table(ttab.tcapth,ttab.selpth,ttab.pwstr,ttab.tcfile,cmst);
                    app.UITable.Data   = ttab;                                         % 更新table状态

                    app.LabelZTZ.Text = '处理完毕！';
                    pause(0.001);
                    outrlts   = table(tcfile, pwstr, yspth,'VariableNames', {'压缩包名称','加密密码','压缩包所在路径'});
                    outname   = [yspath, filesep, app.mfinal, num2str(round(10000*rand)), '.xlsx'];
                    writetable(outrlts,outname,'WriteVariableNames', true,'AutoFitWidth',true);
                    app.LabelZTZ.Text = '密码文件保存完毕，你可以退出本程序或进行其他操作！';
                else
                    app.LabelZTZ.Text = '请先设置好待压缩主路径再试！';
                end
            else
                app.exe7z = '';
                app.LabelZTZ.Text = '必须先设置好7z.exe路径!';
            end
        end

        % Value changed function: DropDownGS
        function DropDownGSValueChanged(app, event)
            val = app.DropDownGS.Value;
            switch(val)
                case '.7z'
                    app.ttype = '-t7z';
                case '.zip'
                    app.ttype = '-tzip';
            end
        end

        % Value changed function: CheckBoxYC
        function CheckBoxYCValueChanged(app, event)
            val = app.CheckBoxYC.Value;
            switch(val)
                case 0
                    app.hename = '-mhe=off';
                case 1
                    app.hename = '-mhe=on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.v100UIFigure = uifigure('Visible', 'off');
            app.v100UIFigure.Name = '巴山批量加密压缩助手 v1.0.0 —— 由巴山学长制作';
            app.Image = uiimage(app.v100UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '7-Zip', '7ziplogo.png');
            app.EditField7zexe = uieditfield(app.v100UIFigure, 'text');
            app.EditField7zexe.Tooltip = {'7z.exe的路径'};
            app.UITable = uitable(app.v100UIFigure);
            app.UITable.ColumnName = {'待处理路径'; '选中'; '密码'; '要锁后名称'; '状态'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true false];
            app.PanelSetRun = uipanel(app.v100UIFigure);
            app.PanelSetRun.Tooltip = {'密码选项设置区'};
            app.CheckBoxSZ = uicheckbox(app.PanelSetRun);
            app.CheckBoxSZ.Text = '数字';
            app.CheckBoxSZ.Value = true;
            app.CheckBoxXX = uicheckbox(app.PanelSetRun);
            app.CheckBoxXX.Text = '小写';
            app.CheckBoxXX.Value = true;
            app.CheckBoxDX = uicheckbox(app.PanelSetRun);
            app.CheckBoxDX.Text = '大写';
            app.CheckBoxDX.Value = true;
            app.CheckBoxZF = uicheckbox(app.PanelSetRun);
            app.CheckBoxZF.ValueChangedFcn = createCallbackFcn(app, @CheckBoxZFValueChanged, true);
            app.CheckBoxZF.Text = '符号';
            app.CheckBoxZF.Value = true;
            app.EditFieldZF = uieditfield(app.PanelSetRun, 'text');
            app.EditFieldZF.Value = '!@#$%.&';
            app.Label_2 = uilabel(app.PanelSetRun);
            app.Label_2.Text = '密码长度';
            app.EditFieldPWlen = uieditfield(app.PanelSetRun, 'numeric');
            app.EditFieldPWlen.Limits = [8 512];
            app.EditFieldPWlen.Value = 32;
            app.ImageLJ = uiimage(app.PanelSetRun);
            app.ImageLJ.ImageClickedFcn = createCallbackFcn(app, @ImageLJClicked, true);
            app.ImageLJ.Tooltip = {'导入需加密的文件夹'};
            app.ImageLJ.ImageSource = fullfile(pathToMLAPP, 'icon', '导入.png');
            app.ImageZX = uiimage(app.PanelSetRun);
            app.ImageZX.ImageClickedFcn = createCallbackFcn(app, @ImageZXClicked, true);
            app.ImageZX.Tooltip = {'执行'};
            app.ImageZX.ImageSource = fullfile(pathToMLAPP, 'icon', '运行.png');
            app.Label_4 = uilabel(app.PanelSetRun);
            app.Label_4.Text = '层级深度';
            app.SpinnerCJ = uispinner(app.PanelSetRun);
            app.SpinnerCJ.Limits = [0 3];
            app.SpinnerCJ.ValueDisplayFormat = '%.0f';
            app.SpinnerCJ.Value = 1;
            app.Label_5 = uilabel(app.PanelSetRun);
            app.Label_5.Text = '格式';
            app.DropDownGS = uidropdown(app.PanelSetRun);
            app.DropDownGS.Items = {'.7z', '.zip'};
            app.DropDownGS.ValueChangedFcn = createCallbackFcn(app, @DropDownGSValueChanged, true);
            app.DropDownGS.Tooltip = {'选择压缩文件格式，默认【.7z】'};
            app.DropDownGS.Value = '.7z';
            app.Label_6 = uilabel(app.PanelSetRun);
            app.Label_6.Text = '线程数';
            app.DropDownXC = uidropdown(app.PanelSetRun);
            app.DropDownXC.Items = {'1', '2', '4', '8', '16', 'none'};
            app.DropDownXC.Editable = 'on';
            app.DropDownXC.Tooltip = {'用于启用多线程压缩或解压缩，此值不能大于CPU实际线程数！！！'};
            app.DropDownXC.Value = '4';
            app.Label_7 = uilabel(app.PanelSetRun);
            app.Label_7.Text = '压缩级别';
            app.DropDownYSJB = uidropdown(app.PanelSetRun);
            app.DropDownYSJB.Items = {'9', '8', '7', '6', '5', '4', '3', '2', '1'};
            app.DropDownYSJB.Tooltip = {'压缩级别越高越有利于提高压缩效率和安全性'};
            app.DropDownYSJB.Value = '9';
            app.CheckBoxYC = uicheckbox(app.PanelSetRun);
            app.CheckBoxYC.ValueChangedFcn = createCallbackFcn(app, @CheckBoxYCValueChanged, true);
            app.CheckBoxYC.Text = '隐藏文件名';
            app.CheckBoxYC.Value = true;
            app.Label_8 = uilabel(app.PanelSetRun);
            app.Label_8.Text = '前缀名称';
            app.EditFieldFM = uieditfield(app.PanelSetRun, 'text');
            app.EditFieldFM.Tooltip = {'用于给压缩包添加前缀名称，可为空。'};
            app.EditFieldFM.Value = '巴山';
            app.ImageSet = uiimage(app.v100UIFigure);
            app.ImageSet.ImageClickedFcn = createCallbackFcn(app, @ImageSetClicked, true);
            app.ImageSet.ImageSource = fullfile(pathToMLAPP, 'icon', '设置.png');
            app.Panel7z = uipanel(app.v100UIFigure);
            app.LabelZTM = uilabel(app.Panel7z);
            app.LabelZTM.Text = '状态栏：';
            app.LabelZTZ = uilabel(app.Panel7z);
            app.LabelZTZ.Text = '';
            % Show the figure after all components are created
            app.v100UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = packFile7z

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.v100UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.v100UIFigure)
        end
    end
end